classdef choiceEventEditApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        CancelButton                  matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
        KeysEdit                      matlab.ui.control.EditField
        ValidkeyscommasepEditFieldLabel  matlab.ui.control.Label
        WindowEdit                    matlab.ui.control.NumericEditField
        ValidwindowsecEditFieldLabel  matlab.ui.control.Label
        JitterBEdit                   matlab.ui.control.NumericEditField
        StarttimejittertosecEditFieldLabel  matlab.ui.control.Label
        JitterAEdit                   matlab.ui.control.NumericEditField
        StarttimejitterfromsecEditFieldLabel  matlab.ui.control.Label
        StartEdit                     matlab.ui.control.NumericEditField
        StarttimesecEditFieldLabel    matlab.ui.control.Label
        KindCHOICELabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        ev struct
        onCommit function_handle
    end
    
    methods (Access = public)
        
        function showModal(app, ~)
            app.UIFigure.WindowStyle = 'modal';
            app.UIFigure.Visible = 'on';
            movegui(app.UIFigure,'center');
        end

    end
    
    methods (Access = private)
        
        function load_(app)
            app.StartEdit.Value = app.ev.tStart;
            app.JitterAEdit.Value = app.ev.jitterA;
            app.JitterBEdit.Value = app.ev.jitterB;
            if ~isfinite(app.ev.validWindow) || app.ev.validWindow<=0, app.ev.validWindow = 1.0; end
            app.WindowEdit.Value = app.ev.validWindow;
            app.KeysEdit.Value = char(app.ev.validKeys);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ev, onCommit)
            app.ev = ev;
            app.onCommit = onCommit;
            app.load_();
            movegui(app.UIFigure, "center");
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.ev.tStart = app.StartEdit.Value;
            app.ev.jitterA = app.JitterAEdit.Value;
            app.ev.jitterB = app.JitterBEdit.Value;
            app.ev.validWindow = app.WindowEdit.Value;
            app.ev.validKeys = string(app.KeysEdit.Value);

            app.onCommit(app.ev);
            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.KindCHOICELabel = uilabel(app.GridLayout);
            app.KindCHOICELabel.Text = 'Kind: CHOICE';
            app.StarttimesecEditFieldLabel = uilabel(app.GridLayout);
            app.StarttimesecEditFieldLabel.Text = 'Start time (sec)';
            app.StartEdit = uieditfield(app.GridLayout, 'numeric');
            app.StarttimejitterfromsecEditFieldLabel = uilabel(app.GridLayout);
            app.StarttimejitterfromsecEditFieldLabel.Text = 'Start time jitter from (sec)';
            app.JitterAEdit = uieditfield(app.GridLayout, 'numeric');
            app.StarttimejittertosecEditFieldLabel = uilabel(app.GridLayout);
            app.StarttimejittertosecEditFieldLabel.Text = 'Start time jitter to (sec)';
            app.JitterBEdit = uieditfield(app.GridLayout, 'numeric');
            app.ValidwindowsecEditFieldLabel = uilabel(app.GridLayout);
            app.ValidwindowsecEditFieldLabel.Text = 'Valid window (sec)';
            app.WindowEdit = uieditfield(app.GridLayout, 'numeric');
            app.WindowEdit.Value = 2;
            app.ValidkeyscommasepEditFieldLabel = uilabel(app.GridLayout);
            app.ValidkeyscommasepEditFieldLabel.Text = 'Valid keys (comma sep)';
            app.KeysEdit = uieditfield(app.GridLayout, 'text');
            app.KeysEdit.Value = 'leftarrow, rightarrow';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = choiceEventEditApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end