classdef eventFlowApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout5           matlab.ui.container.GridLayout
        EventflowLabel        matlab.ui.control.Label
        Ax                    matlab.ui.control.UIAxes
        GridLayout4           matlab.ui.container.GridLayout
        LoginfoTextAreaLabel  matlab.ui.control.Label
        Msg                   matlab.ui.control.TextArea
        GridLayout3           matlab.ui.container.GridLayout
        EventtableLabel       matlab.ui.control.Label
        Tbl                   matlab.ui.control.Table
        GridLayout2           matlab.ui.container.GridLayout
        SaveButton            matlab.ui.control.Button
        CancelButton          matlab.ui.control.Button
        LoadButton            matlab.ui.control.Button
        OKButton              matlab.ui.control.Button
    end


    properties (Access = private)
        % Context menus
        AxMenu matlab.ui.container.ContextMenu
        BlockMenu matlab.ui.container.ContextMenu

        % Data model
        Evts0 table
        nextId double = 1

        % Graphics map: id -> rectangle handle
        rectMap containers.Map

        % Lanes
        laneOrder string = ["choice","cue","stimuli","start"]
        laneY double = 1:4 % center y for each lane
        laneH double = 0.7

        % Hover state
        hoveredId double = NaN
        HoverTip matlab.ui.control.Label
        nextSeq double = 1

        EvtsPreset
    end
    
    properties (Access = public)
        Evts     table
        filepath {mustBeTextScalar} = ''
    end

    methods (Access = private)

        function initData_(app)
            app.Evts0 = table('Size',[0, 14], ...
                'VariableTypes', ["double","string","string","double","double","double","double","double","string","string","double","string","string","double"], ...
                'VariableNames', ["id","kind","modality","tStart","tEnd","jitterA","jitterB","maxDur","identifier","triggerMode","validWindow","validKeys","triggerKey","seq"]);
            app.Evts = app.Evts0;
        end

        function loadPreset_(app, preset)
            % preset can be:
            % - table (events)
            % - string/char path to .mat (containing events table)
            % - struct with field 'events'

            if istable(preset)
                ev = preset;

            elseif mu.isTextScalar(preset)
                p = string(preset);
                if ~endsWith(lower(p), ".mat")
                    error("Preset path must be a .mat file.");
                end
                if ~isfile(p)
                    uialert(app.UIFigure, sprintf("Preset file not found: %s", p), "File missing");
                    return;
                end

                S = load(p);

                % accept several common names
                if isfield(S, "events") && istable(S.events)
                    ev = S.events;
                elseif isfield(S, "TblData") && istable(S.TblData)
                    ev = S.TblData;
                elseif isfield(S, "data") && istable(S.data)
                    ev = S.data;
                elseif isfield(S, "config") && isstruct(S.config)
                    ev = S.config.params.Evts;
                else
                    error("MAT file must contain a table named 'events' (or 'TblData'/'data').");
                end

                app.filepath = p;

            elseif isstruct(preset)
                if isfield(preset, "events") && istable(preset.events)
                    ev = preset.events;
                else
                    error("Struct preset must contain field 'events' as a table.");
                end
            else
                error("Unsupported preset type: %s", class(preset));
            end

            % Normalize & validate schema
            ev = app.normalizeEventsTable_(ev);

            % Validate schedule rules (reuse your validate_ logic)
            [ok, report] = app.validate_(ev);
            if ~ok
                error("Preset schedule is invalid:\n%s", report);
            end

            % Commit
            app.Evts = ev;

            % Update nextId / nextSeq
            if ~isempty(app.Evts)
                if ismember("id", app.Evts.Properties.VariableNames)
                    app.nextId = max(app.Evts.id) + 1;
                end
                if ismember("seq", app.Evts.Properties.VariableNames)
                    app.nextSeq = max(app.Evts.seq) + 1;
                end
            end

            app.syncTable_();
            app.render_();
            app.log_("Loaded preset (" + height(app.Evts) + " events).");
        end

        function savePreset_(app)
            [f, p] = uiputfile(fullfile(fileparts(mfilename("fullpath")), 'config', '*.mat'), 'Save preset as');
            if isequal(f, 0), return; end
            events = app.Evts;
            app.filepath = fullfile(p, f);
            save(app.filepath, "events");
            app.log_("Saved preset to " + string(p) + string(f));
        end

        function ev = normalizeEventsTable_(app, ev)
            % Ensure required columns exist; add defaults when missing.
            % This lets old saved tables still load.

            % Required schema in current app
            wantNames = app.Evts.Properties.VariableNames;
            wantTypes = varfun(@class, app.Evts, 'OutputFormat','cell');

            % 1) If preset is missing some columns, add them with defaults
            for k = 1:numel(wantNames)
                nm = wantNames{k};
                if ~ismember(nm, ev.Properties.VariableNames)
                    % create default column matching type
                    switch wantTypes{k}
                        case 'double'
                            ev.(nm) = NaN(height(ev),1);
                        case 'string'
                            ev.(nm) = strings(height(ev),1);
                        otherwise
                            % fallback
                            ev.(nm) = strings(height(ev),1);
                    end
                end
            end

            % 2) If preset has extra columns, keep them (optional) or drop them
            % Here we keep extras; but ensure at least required columns are present.

            % 3) Convert types to match (lightweight coercion)
            ev.kind = string(ev.kind);
            ev.modality = string(ev.modality);
            ev.triggerMode = string(ev.triggerMode);
            ev.identifier = string(ev.identifier);
            ev.validKeys = string(ev.validKeys);
            ev.triggerKey = string(ev.triggerKey);

            % 4) If seq is missing or NaN, generate by stable row order
            if all(isnan(ev.seq))
                ev.seq = (1:height(ev)).';
            end

            % 5) Normalize tEnd for drawing if missing
            for i = 1:height(ev)
                e = table2struct(ev(i,:));
                e = app.normalizeEvent_(e);
                ev(i,:) = struct2table(e);
            end

            % 6) Sort by preset tStart
            ev = app.sortEvents_(ev);
        end

        function buildUI_(app)
            % Left: Axes
            app.Ax.XGrid = 'on';
            app.Ax.YGrid = 'on';
            app.Ax.YLim = [0.5 4.5];
            app.Ax.YTick = app.laneY;
            app.Ax.YTickLabel = cellstr(upper(app.laneOrder));
            app.Ax.Box = 'on';
            app.Ax.Toolbar.Visible = 'off';
            app.Ax.Title.FontSize = 16;
            xlabel(app.Ax,'Time (s)');
            app.Ax.ButtonDownFcn = @(s,e)app.onAxesClick_(e); % for selection

            % Right: Table + log
            names = app.Evts.Properties.VariableNames;
            editable = true(1, numel(names));
            editable(strcmp(names, 'id')) = false;
            editable(strcmp(names, 'tEnd')) = false;
            editable(strcmp(names, 'seq')) = false;
            app.Tbl.ColumnEditable = editable;
            app.Tbl.CellEditCallback = @(s,e)app.onTableEdit_(e);
            app.Tbl.ColumnName = app.Evts.Properties.VariableNames;

            % Context menu for axes blank area
            app.AxMenu = uicontextmenu(app.UIFigure);
            uimenu(app.AxMenu,'Text','Add Start','MenuSelectedFcn',@(~,~)app.addStart_());
            uimenu(app.AxMenu,'Text','Add Stimuli','MenuSelectedFcn',@(~,~)app.addStimuli_("stimuli"));
            uimenu(app.AxMenu,'Text','Add Cue','MenuSelectedFcn',@(~,~)app.addStimuli_("cue"));
            uimenu(app.AxMenu,'Text','Add Choice','MenuSelectedFcn',@(~,~)app.addChoice_());

            app.Ax.ContextMenu = app.AxMenu;

            % Context menu for blocks
            app.BlockMenu = uicontextmenu(app.UIFigure);
            uimenu(app.BlockMenu,'Text','Edit','MenuSelectedFcn',@(~,~)app.editHovered_());
            uimenu(app.BlockMenu,'Text','Delete','MenuSelectedFcn',@(~,~)app.deleteHovered_());

            % Hover detection
            app.UIFigure.WindowButtonMotionFcn = @(s,e)app.onMouseMove_();
            % Floating tooltip label (custom hover tip)
            app.HoverTip = uilabel(app.UIFigure, ...
                'Visible','off', ...
                'BackgroundColor',[1 1 0.95], ...
                'FontName','Consolas', ...
                'FontSize',13, ...
                'HorizontalAlignment','left', ...
                'VerticalAlignment','top');
            app.HoverTip.WordWrap = 'on';

            % Center app
            movegui(app.UIFigure, "center");
        end

        function updateHoverTip_(app)
            if isnan(app.hoveredId)
                app.HoverTip.Visible = 'off';
                return
            end

            % get event row
            idx = find(app.Evts.id == app.hoveredId, 1);
            if isempty(idx)
                app.HoverTip.Visible = 'off';
                return
            end
            e = app.Evts(idx,:);

            % format text
            tip = app.formatEventTip_(e);

            % mouse position in figure pixels
            % NOTE: uifigure has CurrentPoint in pixels [x y]
            p = app.UIFigure.CurrentPoint; % [x y] in pixels, origin at bottom-left
            x = p(1) + 14;
            y = p(2) + 14;

            % compute size roughly based on text length (simple heuristic)
            % If you want more precise, use a fixed width and let WordWrap handle it.
            w = 330;
            h = 150;

            % keep tooltip inside figure bounds
            figPos = app.UIFigure.Position; % [x y w h]
            maxX = figPos(3) - w - 8;
            maxY = figPos(4) - h - 8;
            x = min(max(x, 8), maxX);
            y = min(max(y, 8), maxY);

            app.HoverTip.Text = tip;
            app.HoverTip.Position = [x y w h];
            app.HoverTip.Visible = 'on';
        end

        function tip = formatEventTip_(app, e)
            kind = upper(string(e.kind));
            id = e.id;
            t0 = e.tStart; t1 = e.tEnd;

            lines = strings(0,1);
            lines(end+1) = sprintf("[%s]  id=%d", kind, id);
            lines(end+1) = sprintf("t_start     : %g sec", t0);

            if e.kind=="start"
                lines(end+1) = sprintf("trigger     : %s", string(e.triggerMode));
                if string(e.triggerMode)=="keyboard"
                    lines(end+1) = sprintf("key         : %s", string(e.triggerKey));
                end
                lines(end+1) = sprintf("jitter      : [%g, %g] sec", e.jitterA, e.jitterB);

            elseif e.kind=="choice"
                lines(end+1) = sprintf("jitter      : [%g, %g] s", e.jitterA, e.jitterB);
                lines(end+1) = sprintf("window      : %g s", e.validWindow);
                lines(end+1) = sprintf("keys        : %s", string(e.validKeys));
                lines(end+1) = sprintf("t_end       : %g s", t1);

            elseif e.kind=="stimuli" || e.kind=="cue"
                lines(end+1) = sprintf("type        : %s", upper(string(e.modality)));
                lines(end+1) = sprintf("jitter      : [%g, %g] sec", e.jitterA, e.jitterB);
                lines(end+1) = sprintf("max_duration: %g sec", e.maxDur);
                if strlength(string(e.identifier))>0
                    lines(end+1) = sprintf("identifier  : %s", string(e.identifier));
                end
                lines(end+1) = sprintf("t_end       : %g sec", t1);
            else
                lines(end+1) = sprintf("t_end       : %g sec", t1);
            end

            tip = strjoin(lines, newline);
        end


        function onAxesClick_(app, ~)
            % Optional: left click blank to clear selection
        end

        function onMouseMove_(app)
            % Determine hovered rectangle by checking hit-test on rectangles.
            % Since MATLAB doesn't expose "which object under cursor" robustly for uifigure,
            % we do a simple search in event rectangles by comparing mouse x/y to each rect.
            cp = app.Ax.CurrentPoint;
            x = cp(1,1); y = cp(1,2);

            hitIds = [];
            hitSeq = [];

            for i = 1:height(app.Evts)
                id = app.Evts.id(i);
                if ~isKey(app.rectMap, id), continue; end
                r = app.rectMap(id);
                if ~isvalid(r), continue; end
                pos = r.Position;

                if x>=pos(1) && x<=pos(1)+pos(3) && y>=pos(2) && y<=pos(2)+pos(4)
                    hitIds(end+1,1) = id; %#ok<AGROW>
                    hitSeq(end+1,1) = app.Evts.seq(i); %#ok<AGROW>
                end
            end

            if isempty(hitIds)
                newHover = NaN;
            else
                % choose latest added (largest seq)
                [~, k] = max(hitSeq);
                newHover = hitIds(k);
            end

            if ~(isequaln(newHover, app.hoveredId))
                app.hoveredId = newHover;
                app.updateHoverStyle_();
            end

            if ~(isequaln(newHover, app.hoveredId))
                app.hoveredId = newHover;
                app.updateHoverStyle_();
            end

            % Update tooltip (even if same hover id, position should follow mouse)
            app.updateHoverTip_();
        end

        function updateHoverStyle_(app)
            % simple highlight
            ids = app.Evts.id;
            for k = 1:numel(ids)
                id = ids(k);
                if ~isKey(app.rectMap,id), continue; end
                r = app.rectMap(id);
                if ~isvalid(r), continue; end
                if id == app.hoveredId
                    r.LineWidth = 2.5;
                else
                    r.LineWidth = 1.0;
                end
            end
        end

        function syncTable_(app)
            app.Tbl.Data = app.Evts;
        end

        function log_(app, msg)
            ts = datetime('now','Format','yyyy-MM-dd HH:mm:ss');
            line = "[" + string(ts) + "] " + string(msg);
            app.Msg.Value = [line; app.Msg.Value];
        end

        % ---------- Add/Edit/Delete ----------
        function addStart_(app)
            % if exists, edit instead
            idx = find(app.Evts.kind=="start",1);
            if ~isempty(idx)
                app.log_("Start exists: opening editor.");
                app.editStart_(app.Evts.id(idx));
                return
            end

            % default start event
            ev = app.makeEmptyEvent_("start");
            ev.tStart = 0; ev.tEnd = 0;
            ev.triggerMode = "auto";
            ev.triggerKey = "";

            app.openStartEditor_(ev, "add");
        end

        function editStart_(app, id)
            row = app.getEventById_(id);
            app.openStartEditor_(row, "edit");
        end

        function addStimuli_(app, kind)
            ev = app.makeEmptyEvent_(kind);
            ev.modality = "auditory";
            ev.tStart = 0.5;
            ev.jitterA = 0; ev.jitterB = 0;
            ev.maxDur = 0.3;
            ev.tEnd = ev.tStart + ev.maxDur;
            ev.identifier = "";

            app.openStimuliEditor_(ev, "add");
        end

        function editStimuli_(app, id)
            row = app.getEventById_(id);
            app.openStimuliEditor_(row, "edit");
        end

        function addChoice_(app)
            ev = app.makeEmptyEvent_("choice");
            ev.tStart = 1.0;
            ev.jitterA = 0; ev.jitterB = 0;
            ev.validWindow = 1.0;
            ev.tEnd = ev.tStart + ev.validWindow;
            ev.validKeys = "leftarrow, rightarrow";

            app.openChoiceEditor_(ev, "add");
        end

        function editChoice_(app, id)
            row = app.getEventById_(id);
            app.openChoiceEditor_(row, "edit");
        end

        function editHovered_(app)
            if isnan(app.hoveredId)
                return
            end
            kind = app.Evts.kind(app.Evts.id==app.hoveredId);
            switch kind
                case "start"
                    app.editStart_(app.hoveredId);
                case {"stimuli","cue"}
                    app.editStimuli_(app.hoveredId);
                case "choice"
                    app.editChoice_(app.hoveredId);
            end
        end

        function deleteHovered_(app)
            if isnan(app.hoveredId), return; end
            id = app.hoveredId;
            app.Evts(app.Evts.id==id,:) = [];
            if isKey(app.rectMap,id)
                r = app.rectMap(id);
                if isvalid(r), delete(r); end
                remove(app.rectMap,id);
            end
            app.hoveredId = NaN;
            app.syncTable_();
            app.render_();
            app.log_("Deleted event id=" + id);
        end

        % ---------- Editors ----------
        function openStartEditor_(app, ev, mode)
            dlg = startEventEditApp(ev, @(out)app.onEditorCommit_(out, mode));
            dlg.showModal(app.UIFigure);
        end

        function openStimuliEditor_(app, ev, mode)
            dlg = stimuliEventEditApp(ev, @(out)app.onEditorCommit_(out, mode));
            dlg.showModal(app.UIFigure);
        end

        function openChoiceEditor_(app, ev, mode)
            dlg = choiceEventEditApp(ev, @(out)app.onEditorCommit_(out, mode));
            dlg.showModal(app.UIFigure);
        end

        function onEditorCommit_(app, ev, mode)
            % Normalize single event first
            ev = app.normalizeEvent_(ev);
        
            % Build temporary table
            tmp = app.Evts;
            if mode == "add"
                ev.id = app.nextId;
                app.nextId = app.nextId + 1;
        
                ev.seq = app.nextSeq;
                app.nextSeq = app.nextSeq + 1;
        
                tmp = [tmp; struct2table(ev)];
            else
                tmp(tmp.id == ev.id, :) = struct2table(ev);
            end
        
            % Normalize whole table (including sort)
            tmp = app.normalizeEventsTable_(tmp);
        
            % Validate
            [ok, report] = app.validate_(tmp);
            if ~ok
                uialert(app.UIFigure, report, "Invalid schedule");
                return
            end
        
            % Commit
            app.Evts = tmp;
        
            if mode == "add"
                app.log_("Added " + ev.kind + " id=" + ev.id);
            else
                app.log_("Edited " + ev.kind + " id=" + ev.id);
            end
        
            app.syncTable_();
            app.render_();
        end

        function ev = normalizeEvent_(app, ev)
            % keep tEnd consistent for drawing
            switch ev.kind
                case "start"
                    ev.tStart = 0; ev.tEnd = 0;
                case {"stimuli","cue"}
                    if ~isfinite(ev.maxDur) || ev.maxDur<=0
                        ev.maxDur = 0.3;
                    end
                    ev.tEnd = ev.tStart + ev.maxDur;
                case "choice"
                    if ~isfinite(ev.validWindow) || ev.validWindow<=0
                        ev.validWindow = 1.0;
                    end
                    ev.tEnd = ev.tStart + ev.validWindow;
            end
        end

        % ---------- Validation rules ----------
        function [ok, report] = validate_(app, ev)
            tol = 1e-9;
            msgs = strings(0,1);

            % Start unique and fixed
            startIdx = find(ev.kind=="start");
            if numel(startIdx) > 1
                msgs(end+1) = "Start event must be single.";
            end
            if ~isempty(startIdx)
                s = ev(startIdx,:);
                if abs(s.tStart) > tol || abs(s.tEnd) > tol
                    msgs(end+1) = "Start must be fixed at tStart=tEnd=0.";
                end
                if s.triggerMode=="keyboard" && strlength(strtrim(s.triggerKey))==0
                    msgs(end+1) = "Start triggerMode=keyboard requires a non-empty key.";
                end
            end

            % Basic timing validity
            if any(ev.tStart < -tol) || any(ev.tEnd < ev.tStart - tol)
                msgs(end+1) = "Invalid timing: tStart>=0 and tEnd>=tStart required.";
            end

            % Jitter validity (for those that use it)
            hasJ = (ev.kind=="stimuli") | (ev.kind=="cue") | (ev.kind=="choice");
            if any(ev.jitterA(hasJ) > ev.jitterB(hasJ) + tol)
                msgs(end+1) = "Invalid jitter range: jitterA must be <= jitterB.";
            end

            % Stimuli/Cue overlap: only allowed if modality differs
            sc = ev(ev.kind=="stimuli" | ev.kind=="cue",:);
            if height(sc) > 1
                sc = sortrows(sc,"tStart");
                for i = 1:height(sc)-1
                    for j = i+1:height(sc)
                        if sc.tStart(j) >= sc.tEnd(i) - tol
                            break
                        end
                        % overlap found
                        if sc.modality(i) == sc.modality(j)
                            msgs(end+1) = sprintf("Stimuli/Cue overlap invalid: same modality (%s) between id %d and %d.", ...
                                sc.modality(i), sc.id(i), sc.id(j));
                        end
                    end
                end
            end

            % Choice overlap forbidden (default strict)
            ch = ev(ev.kind=="choice",:);
            if height(ch) > 1
                ch = sortrows(ch,"tStart");
                for i = 2:height(ch)
                    if ch.tStart(i) < ch.tEnd(i-1) - tol
                        msgs(end+1) = sprintf("Choice overlap invalid between id %d and %d.", ch.id(i-1), ch.id(i));
                    end
                end
            end

            % Choice keys non-empty
            if any(ev.kind=="choice")
                for i = find(ev.kind=="choice").'
                    keys = strtrim(split(ev.validKeys(i), ","));
                    keys(keys=="") = [];
                    if isempty(keys)
                        msgs(end+1) = sprintf("Choice id %d: validKeys cannot be empty.", ev.id(i));
                    end
                end
            end

            % Identifer
            idx = ev.kind=="stimuli" | ev.kind=="cue";
            if sum(idx) > 1 && all(ev.identifier(idx)=="")
                msgs(end+1) = sprintf("Identifer missing: when the number of stimuli/cue exceeds 1, each should be given an identifier.");
            end

            ok = isempty(msgs);
            report = strjoin(msgs, newline);
        end

        % ---------- Rendering ----------
        function render_(app)
            cla(app.Ax);
            hold(app.Ax,'on');

            ev = app.Evts;

            % Lanes lines
            xMax = max([5, max(ev.tEnd,[],'omitnan') + 0.5]);
            xMin = min([0; ev.tStart + ev.jitterA]);
            app.Ax.XLim = [xMin xMax];
            for k = 1:numel(app.laneY)
                y = app.laneY(k);
                plot(app.Ax, [xMin xMax], [y y], 'k-');
            end

            % Clear old rects
            keys = app.rectMap.keys;
            for i = 1:numel(keys)
                r = app.rectMap(keys{i});
                if isvalid(r), delete(r); end
            end
            app.rectMap = containers.Map('KeyType','double','ValueType','any');

            % Draw blocks
            for i = 1:height(ev)
                e = ev(i,:);
                y = app.laneCenter_(e.kind);
                h = app.laneH;
                x = e.tStart; w = max(e.tEnd - e.tStart, 0.05); % minimal visible width

                % color hint by modality
                if e.kind=="stimuli" || e.kind=="cue"
                    if e.modality=="auditory"
                        faceColor = [0.85 0.92 1.0];
                    elseif e.modality=="visual"
                        faceColor = [0.90 1.0 0.88];
                    end
                elseif e.kind=="choice"
                    faceColor = [1.0 0.92 0.85];
                elseif e.kind=="start"
                    faceColor = [0.95 0.95 0.95];
                end

                % --- Draw jitter region (if any) ---
                if isfinite(e.jitterA) && isfinite(e.jitterB) && e.jitterA ~= e.jitterB
                    js = e.tStart + e.jitterA;
                    je = e.tEnd   + e.jitterB;
                
                    % ensure left < right
                    xj = min(js, je);
                    wj = abs(je - js);
                
                    % y range same as event lane
                    yj = y - h/2;
                    hj = h;
                
                    rj = rectangle(app.Ax, ...
                        'Position', [xj, yj, wj, hj], ...
                        'EdgeColor', [0.3 0.3 0.3], ...
                        'LineStyle', '--', ...
                        'LineWidth', 1, ...
                        'FaceColor', faceColor, ...
                        'FaceAlpha', 0.7);
                
                    % make sure jitter region is NOT interactive
                    rj.HitTest = 'off';
                end

                r = rectangle(app.Ax, 'Position',[x, y-h/2, w, h], ...
                    'Curvature',0.1, 'LineWidth',1, 'FaceColor',faceColor, ...
                    'EdgeColor',[0.2 0.2 0.2]);

                % attach context menu for block
                r.UIContextMenu = app.BlockMenu;

                % store
                app.rectMap(e.id) = r;

                % label
                label = upper(char(e.kind));
                if (e.kind=="stimuli" || e.kind=="cue") && strlength(e.modality)>0
                    label = label + " (" + upper(e.modality) + ")";
                end
                text(app.Ax, x + 0.02, y, label, 'VerticalAlignment','middle','FontSize',12);
            end

            hold(app.Ax,'off');

            app.syncTable_();
        end

        function y = laneCenter_(app, kind)
            kind = string(kind);
            idx = find(app.laneOrder==kind,1);
            y = app.laneY(idx);
        end

        % ---------- Utilities ----------
        function ev = makeEmptyEvent_(app, kind)
            ev = struct;
            ev.id = NaN; % assigned on commit
            ev.kind = string(kind);
            ev.modality = "";
            ev.tStart = 0; ev.tEnd = 0;
            ev.jitterA = 0; ev.jitterB = 0;
            ev.maxDur = NaN;
            ev.identifier = "";
            ev.triggerMode = "";
            ev.validWindow = NaN;
            ev.validKeys = "";
            ev.triggerKey = "";
            ev.seq = NaN;
        end

        function row = getEventById_(app, id)
            idx = find(app.Evts.id==id,1);
            if isempty(idx), error("Event id not found."); end
            row = table2struct(app.Evts(idx,:));
        end

        function onTableEdit_(app, e)
            newEv = app.Tbl.Data;
        
            % Normalize whole table after direct edit
            newEv = app.normalizeEventsTable_(newEv);
        
            [ok, report] = app.validate_(newEv);
            if ~ok
                uialert(app.UIFigure, report, "Invalid schedule");
                app.syncTable_();
                return
            end
        
            app.Evts = newEv;
            app.syncTable_();
            app.render_();
        end
        
        function ev = sortEvents_(app, ev)
            if isempty(ev)
                return
            end
        
            n = height(ev);
            origOrder = (1:n)';
        
            % tie-break priority:
            % start < cue < others < choice
            priority = ones(n,1);
            priority(ev.kind == "start")  = -1;
            priority(ev.kind == "cue")    = 0;
            priority(ev.kind == "choice") = 2;
        
            ev.sort_tStart   = ev.tStart;
            ev.sort_priority = priority;
            ev.sort_orig     = origOrder;
        
            ev = sortrows(ev, ...
                {'sort_tStart','sort_priority','sort_orig'}, ...
                {'ascend','ascend','ascend'});
        
            ev(:, {'sort_tStart','sort_priority','sort_orig'}) = [];

            % Reset seq to match current row order
            ev.seq = (1:height(ev)).';
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, preset)
            narginchk(1, 2);

            if nargin < 2
                preset = [];
            end

            app.rectMap = containers.Map('KeyType', 'double', 'ValueType', 'any');
            app.initData_();
            app.buildUI_();

            if ~isempty(preset)
                app.loadPreset_(preset);
                app.EvtsPreset = app.Evts;
            else
                app.EvtsPreset = app.Evts0;
            end

            app.render_();

            set(app.UIFigure, "WindowState", "maximized");
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if ~isequaln(app.Evts, app.EvtsPreset)
                c = uiconfirm(app.UIFigure, ...
                              "Current settings will override your event flow settings in rule file. Continue?", ...
                              "Confirm", ...
                              "Options", ["Yes", "No", "Cancel"], ...
                              "DefaultOption", 2, ...
                              "CancelOption", 3);

                switch c
                    case "Yes"
                        
                    case "No"
                        app.Evts = app.EvtsPreset;
                    case "Cancel"
                        return;
                end
            end
            uiresume(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Evts = app.EvtsPreset;
            uiresume(app.UIFigure);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            currentFolder = fileparts(mfilename("fullpath"));
            configPath = fullfile(currentFolder, "config");
            [f, p] = uigetfile(fullfile(configPath, "*.mat"));
            if isequal(f, 0)
                return;
            end
            app.loadPreset_(fullfile(p, f));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CancelButtonPushed();
            delete(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.savePreset_()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Event Flow Designer';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OKButton = uibutton(app.GridLayout2, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Tbl = uitable(app.GridLayout3);
            app.Tbl.ColumnName = '';
            app.Tbl.RowName = {};
            app.EventtableLabel = uilabel(app.GridLayout3);
            app.EventtableLabel.Text = 'Event table';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Msg = uitextarea(app.GridLayout4);
            app.Msg.Editable = 'off';
            app.LoginfoTextAreaLabel = uilabel(app.GridLayout4);
            app.LoginfoTextAreaLabel.Text = 'Log info';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Ax = uiaxes(app.GridLayout5);
            xlabel(app.Ax, 'Time (s)')
            colormap(app.Ax, 'parula')
            app.EventflowLabel = uilabel(app.GridLayout5);
            app.EventflowLabel.Text = 'Event flow';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eventFlowApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end