classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        StimulationSystemPanel  matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        PhaseSelectPanel        matlab.ui.container.Panel
        GridLayout6             matlab.ui.container.GridLayout
        RulesPathEditField      matlab.ui.control.EditField
        RulesSelectButton       matlab.ui.control.Button
        PhaseSelectTree         matlab.ui.container.CheckBoxTree
        ControlPanel            matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        MonitorButton           matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        AddSubjectButton        matlab.ui.control.Button
        SetParamsButton         matlab.ui.control.Button
        NextButton              matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        DataPathPanel           matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        PathSelectButton        matlab.ui.control.Button
        DataPathEditField       matlab.ui.control.EditField
        StatePanel              matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        StateLabel              matlab.ui.control.Label
        StateLamp               matlab.ui.control.Lamp
    end


    properties (Access = public)
        subjectInfo
        params

        dataPath
        rulesPath

        pIDList
        protocolList
        pIDIndex
        
        mTimer timer
        intervalMax = 10 % min

        status    {mustBeMember(status, {'start', 'stop'})} = 'stop'
        terminate (1,1) logical = false
        behavApp

        trialsData
    end
    
    methods (Access = public)
        
        function timerInit(app)
            app.mTimer = timer;
            app.mTimer.Period = 1;
            app.mTimer.ExecutionMode = 'fixedSpacing';
            app.mTimer.TimerFcn = @(~, ~) timerHandler(app);
        end

        function timerHandler(app)
            currentTime = app.intervalMax * 60 - app.mTimer.TasksExecuted;
            
            if currentTime == 0
                NextButtonPushed(app, []);
            else
                app.NextButton.Text = ['NEXT PHASE(', num2str(currentTime), 's)'];
                drawnow;
            end

        end

        function onAllExpDone(app)
            app.AddSubjectButton.Enable = 'on';
            app.SetParamsButton.Enable = 'on';
            app.StartButton.Enable = 'off';
            app.NextButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            app.PhaseSelectPanel.Enable = 'on';
            app.DataPathPanel.Enable = 'on';
            app.MonitorButton.Enable = 'on';
            app.StateLabel.Text = 'All experiments are done!';
        end

        function onOnePhaseDone(app)
            app.NextButton.Enable = 'on';
            app.MonitorButton.Enable = 'on';
            app.timerInit;
            start(app.mTimer);
        end

        function sendCode(app, code)
            tNow = datetime("now", "Format", "yyyy-MM-dd HH:mm:ss.SSS");
            disp(['Trigger: ', num2str(code), ' (', char(tNow), ')']);

            triggerType = app.params.triggerType;
            ioObj = app.params.ioObj;
            addressLTP = app.params.address;

            switch lower(triggerType)
                case 'ltp'
                    % For LTP (curry 8)
                    io64(ioObj, addressLTP, code);
                    WaitSecs(0.01);
                    io64(ioObj, addressLTP, 0);
                case 'triggerbox'
                    % For neuracle
                    ioObj.OutputEventData(code);
                case 'com'
                    % For COM
                    write(ioObj, code, 'uint8');
                case 'none'
                    % No trigger
                otherwise
                    error("Invalid trigger type: %s", triggerType);
            end
        
        end

    end
    
    methods (Access = private)
        
        function createNodes(app)
            app.PhaseSelectTree.Children.delete;

            if ~exist(app.rulesPath, "file")
                uialert(app.UIFigure, sprintf("Rule file: %s does not exist! Please reselect rule file.", app.rulesPath), "File Missing");
                return;
            end

            tb = readtable(app.rulesPath);
            app.protocolList.Text = cellfun(@(x, y) strcat(x, '-', y), tb.node0Hint, tb.nodeHint, "UniformOutput", false);
            app.protocolList.Text = unique(app.protocolList.Text, 'stable');
            app.protocolList.pID  = unique(tb.pID, 'stable');

            % Create node0
            node0Hint = unique(tb.node0Hint, 'stable');
            for index = 1:length(node0Hint)
                node0(index) = uitreenode(app.PhaseSelectTree);
                node0(index).NodeData = 10000 + index;
                node0(index).Text = node0Hint{index};
            end

            % Create node
            n = 0;
            for index0 = 1:length(node0)
                idx = strcmp(tb.node0Hint, node0Hint{index0});
                pID = unique(tb.pID(idx), 'stable');
                nodeHint = unique(tb.nodeHint(idx), 'stable');
                for index = 1:length(pID)
                    n = n + 1;
                    node(n) = uitreenode(node0(index0));
                    node(n).NodeData = pID(index);
                    node(n).Text = nodeHint{index};
                end
            end

            expand(app.PhaseSelectTree);
        end

        function InitTrigger(app)
            triggerType = app.params.triggerType;
            COM = app.params.COM;

            switch lower(triggerType)
                case 'ltp'
                    % For curry8 and LTP test
                    app.params.ioObj = io64;
                    [~] = io64(app.params.ioObj);
                case 'triggerbox'
                    % For neuracle
                    app.params.ioObj = TriggerBox();
                case 'com'
                    app.params.ioObj = serialport(strcat("COM", num2str(COM)), 115200);
                case 'none'
                    app.params.ioObj = [];
                otherwise
                    error("Invalid trigger type: %s", triggerType);
            end
        
            return;
        end

        function getCurrentEventFlow(app)
            % Get event flow for current pID

            % current rule
            currentFolder = fileparts(mfilename("fullpath"));
            rules = readtable(app.rulesPath);
            pID = app.pIDList(app.pIDIndex);
            rules = rules(rules.pID == pID, :);

            % load event flow
            if ~app.params.useSettingEventFlow || ~isfield(app.params, "Evts") || isempty(app.params.Evts)
                p = unique(rules.eventFlow);
                p = mu.getabspath(p{1});

                if isfile(p)
                    % first: try to use event flow from excel table
                    S = load(p, "events");
                    app.params.Evts = S.events;
                elseif exist(fullfile(currentFolder, "config", "eventFlow_default.mat"), "file")
                    % second: try to load from common setting
                    S = load(fullfile(currentFolder, "config", "eventFlow_default.mat"), "events");
                    app.params.Evts = S.events;
                else
                    % third: file missing, call event flow editor
                    evtApp = eventFlowApp();
                    uiwait(evtApp.UIFigure);
                    app.params.Evts = evtApp.Evts;
                    delete(evtApp);
                end
                
            end

            return;
        end

        function fsDevice = getSystemFs(app)
            try
                InitializePsychSound;
                pahandle = PsychPortAudio('Open', [], 1, 2, [], 2);
                st = PsychPortAudio('GetStatus', pahandle);
                fsDevice = st.SampleRate;
                PsychPortAudio('Close', pahandle);
            catch ME
                uialert(app.UIFigure, "Fail to get system audio output sample rate. Check PTB-3 setup!", "Error");
                fsDevice = [];
            end

            return;
        end

        function saveData(app)
            rules = readtable(app.rulesPath);
            pID = app.pIDList(app.pIDIndex);
            rules = rules(rules.pID == pID, :);
            trialsData = app.trialsData; %#ok<ADPROP>

            f = fullfile(app.dataPath, [char(datetime("now", 'Format', 'yyyyMMdd')), '-', app.subjectInfo.ID]);
            p = fullfile(f, sprintf('%g.mat', pID));
            if exist(p, "file")
                files = dir(fullfile(f, sprintf('%g_*.mat', pID)));
                p = fullfile(f, sprintf('%g_%d.mat', pID, numel(files) + 1));
            end
            save(p, "trialsData", "rules");
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Stimulation system';

            currentFolder = fileparts(mfilename("fullpath"));
            cd(currentFolder);
            addpath(genpath(currentFolder));

            % Init PTB and get system output sample rate
            fsDevice = app.getSystemFs(); % Hz

            p = fullfile(currentFolder, "config", "config.mat");
            if exist(p, "file")
                temp = load(p);
                if isfield(temp, "config")
                    config = temp.config;
                end
            else
                config = struct();
            end
            if ~isfield(config, "params")
                temp = struct();
                temp.nRepeat = 40;
                temp.fs = fsDevice;
                temp.volume = 0.3;
                temp.triggerType = 'None';
                temp.address = hex2dec('378');
                temp.COM = 1;
                temp.useSettingnRepeat = false;
                temp.useSettingEventFlow = false;
                temp.Evts = [];
                config.params = temp;
            end
            if isfield(config, "params"     ), app.params      = config.params;      end
            if isfield(config, "dataPath"   ), app.dataPath    = config.dataPath;    end
            if isfield(config, "subjectInfo"), app.subjectInfo = config.subjectInfo; end
            if isfield(config, "rulesPath")
                app.rulesPath = config.rulesPath;
            else
                app.rulesPath = fullfile(currentFolder, "rules", "rules.xlsx");
            end

            if isempty(app.dataPath)
                app.dataPath = fullfile(currentFolder, "Data");
            else
                app.dataPath = mu.getabspath(app.dataPath);
            end
            
            app.DataPathEditField.Value = app.dataPath;
            app.status = 'stop';
            app.StateLamp.Color = [0.8, 0.8, 0.8];

            app.RulesPathEditField.Value = app.rulesPath;
            createNodes(app);

            movegui(app.UIFigure, "center");
        end

        % Button pushed function: AddSubjectButton
        function AddSubjectButtonPushed(app, event)
            subjectInfoInputApp(app, app.subjectInfo);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                currentFolder = fileparts(mfilename("fullpath"));
                addpath(currentFolder, '-begin');

                % data path
                app.dataPath = mu.getabspath(app.dataPath);

                % init IO for trigger
                if isfield(app.params, "ioObj")
                    delete(app.params.ioObj);
                end
                app.InitTrigger();

                % phase selection
                if isempty([app.PhaseSelectTree.CheckedNodes])
                    error("Please select phase");
                end
                app.pIDList = [app.PhaseSelectTree.CheckedNodes.NodeData];
                app.pIDList(app.pIDList >= 10000) = [];
                app.pIDIndex = 1;
                app.StateLabel.Text = app.protocolList.Text{app.protocolList.pID == app.pIDList(app.pIDIndex)};
                
                % get current event flow
                app.getCurrentEventFlow();

                % save config to files
                config.params = app.params;
                config.dataPath = app.dataPath;
                config.rulesPath = app.rulesPath;
                config.subjectInfo = app.subjectInfo;
                config.time = datetime("now", 'Format', 'yyyy-MM-dd HH:mm:ss');
                f = fullfile(app.dataPath, [char(datetime("now", 'Format', 'yyyyMMdd')), '-', app.subjectInfo.ID]);
                if ~exist(f, "dir")
                    mkdir(f);
                end
                save(fullfile(f, "config.mat"), "config");
                save(fullfile(currentFolder, "config", "config.mat"), "config");

                % UI change
                app.DataPathEditField.Value = app.dataPath;
                app.AddSubjectButton.Enable = 'off';
                app.SetParamsButton.Enable = 'off';
                app.StartButton.Enable = 'off';
                app.StopButton.Enable = 'on';
                app.PhaseSelectPanel.Enable = 'off';
                app.DataPathPanel.Enable = 'off';
                app.MonitorButton.Enable = 'off';
                app.status = 'start';
                app.StateLamp.Color = [0, 1, 0];
                app.terminate = false;
                drawnow;

                % present stimuli
                app.trialsData = presentStimuli(app);
                app.trialsData = app.trialsData([app.trialsData.trialIndex]);

                % Save
                app.saveData();
                
                % end of phase
                pID = app.pIDList(app.pIDIndex);
                if strcmp(app.status, 'start')
                    if pID ~= app.pIDList(end)
                        playAudio(fullfile(fileparts(mfilename("fullpath")), "sounds\defaults\end of phase.mp3"), app.params.fs);
                    else
                        playAudio(fullfile(fileparts(mfilename("fullpath")), "sounds\defaults\end of all.mp3"), app.params.fs);
                    end
                end

            catch ME
                uialert(app.UIFigure, ME.message, "Error message");
            end
        end

        % Button pushed function: SetParamsButton
        function SetParamsButtonPushed(app, event)
            paramsSettingApp(app, app.params);
        end

        % Button pushed function: PathSelectButton
        function PathSelectButtonPushed(app, event)
            temp = uigetdir;

            if temp ~= 0
                app.dataPath = temp;
                app.DataPathEditField.Value = temp;
            end
        end

        % Value changed function: DataPathEditField
        function DataPathEditFieldValueChanged(app, event)
            app.dataPath = app.DataPathEditField.Value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.terminate = true;
            drawnow;

            app.AddSubjectButton.Enable = 'on';
            app.SetParamsButton.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.NextButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            app.PhaseSelectPanel.Enable = 'on';
            app.DataPathPanel.Enable = 'on';
            app.MonitorButton.Enable = 'on';

            app.StateLabel.Text = ['Terminated at: ', app.protocolList.Text{app.protocolList.pID == app.pIDList(app.pIDIndex)}];

            if ~isempty(app.mTimer)
                stop(app.mTimer);
                delete(app.mTimer);
            end

            playhint = false;
            if strcmp(app.status, 'start')
                playhint = true;
            end
            app.status = 'stop';

            app.NextButton.Text = 'NEXT PHASE';
            app.StateLamp.Color = [0.8, 0.8, 0.8];
            drawnow;

            if playhint
                try
                    playAudio(fullfile(fileparts(mfilename("fullpath")), "sounds\defaults\terminate.mp3"), app.params.fs);
                catch ME
                    uialert(app.UIFigure, ME.message, "Error message");
                end
            end

            % Save
            app.saveData();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'NEXT PHASE';
            app.MonitorButton.Enable = 'off';
            drawnow;
            
            if ~isempty(app.mTimer)
                stop(app.mTimer);
                delete(app.mTimer);
            end

            app.pIDIndex = app.pIDIndex + 1;

            if app.pIDIndex <= length(app.pIDList)
                app.StateLabel.Text = app.protocolList.Text{app.protocolList.pID == app.pIDList(app.pIDIndex)};
                drawnow;
                
                % Get current event flow
                app.getCurrentEventFlow();

                % present stimuli
                app.trialsData = presentStimuli(app);
                app.trialsData = app.trialsData([app.trialsData.trialIndex]);

                % Save
                app.saveData();

                % end of phase
                pID = app.pIDList(app.pIDIndex);
                if pID ~= app.pIDList(end)
                    playAudio(fullfile(fileparts(mfilename("fullpath")), "sounds\defaults\end of phase.mp3"), app.params.fs);
                else
                    playAudio(fullfile(fileparts(mfilename("fullpath")), "sounds\defaults\end of all.mp3"), app.params.fs);
                end

            else
                StopButtonPushed(app, event);
            end
            
        end

        % Value changed function: RulesPathEditField
        function RulesPathEditFieldValueChanged(app, event)
            app.rulesPath = app.RulesPathEditField.Value;

            try
                createNodes(app);
            catch ME
                uialert(app.UIFigure, ME.message, "Error message");
            end
        end

        % Button pushed function: RulesSelectButton
        function RulesSelectButtonPushed(app, event)
            [temp, path] = uigetfile(fullfile(fileparts(mfilename("fullpath")), 'rules\*.xlsx'), 'Pick a rules file');

            if temp ~= 0
                app.rulesPath = fullfile(path, temp);
                app.RulesPathEditField.Value = fullfile(path, temp);
                createNodes(app);
            end
        end

        % Button pushed function: MonitorButton
        function MonitorButtonPushed(app, event)
            app.behavApp = behaviorPlotApp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.StimulationSystemPanel = uipanel(app.GridLayout);
            app.StimulationSystemPanel.Title = 'Stimulation System';
            app.GridLayout2 = uigridlayout(app.StimulationSystemPanel);
            app.StatePanel = uipanel(app.GridLayout2);
            app.StatePanel.Title = 'SYSTEM STATUS';
            app.GridLayout3 = uigridlayout(app.StatePanel);
            app.StateLamp = uilamp(app.GridLayout3);
            app.StateLabel = uilabel(app.GridLayout3);
            app.StateLabel.Tag = 'stateLabel';
            app.StateLabel.Text = 'Idle';
            app.DataPathPanel = uipanel(app.GridLayout2);
            app.DataPathPanel.Title = 'DATA DIRECTORY';
            app.GridLayout4 = uigridlayout(app.DataPathPanel);
            app.DataPathEditField = uieditfield(app.GridLayout4, 'text');
            app.DataPathEditField.ValueChangedFcn = createCallbackFcn(app, @DataPathEditFieldValueChanged, true);
            app.DataPathEditField.Tag = 'dataPath';
            app.DataPathEditField.Placeholder = 'Root path of data storage ([date-subject ID] directory will be created)';
            app.PathSelectButton = uibutton(app.GridLayout4, 'push');
            app.PathSelectButton.ButtonPushedFcn = createCallbackFcn(app, @PathSelectButtonPushed, true);
            app.PathSelectButton.Tag = 'pathSelectButton';
            app.PathSelectButton.Text = '···';
            app.ControlPanel = uipanel(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.ControlPanel);
            app.StopButton = uibutton(app.GridLayout5, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Tag = 'stopButton';
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'TERMINATE';
            app.NextButton = uibutton(app.GridLayout5, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tag = 'nextButton';
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'NEXT PHASE';
            app.SetParamsButton = uibutton(app.GridLayout5, 'push');
            app.SetParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SetParamsButtonPushed, true);
            app.SetParamsButton.Tag = 'setParamsButton';
            app.SetParamsButton.Text = 'SETTINGS';
            app.AddSubjectButton = uibutton(app.GridLayout5, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubjectButtonPushed, true);
            app.AddSubjectButton.Tag = 'addSubjectButton';
            app.AddSubjectButton.Text = 'SUBJECT';
            app.StartButton = uibutton(app.GridLayout5, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tag = 'startButton';
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'START';
            app.MonitorButton = uibutton(app.GridLayout5, 'push');
            app.MonitorButton.ButtonPushedFcn = createCallbackFcn(app, @MonitorButtonPushed, true);
            app.MonitorButton.Text = 'MONITOR';
            app.PhaseSelectPanel = uipanel(app.GridLayout2);
            app.PhaseSelectPanel.Title = 'RULES';
            app.GridLayout6 = uigridlayout(app.PhaseSelectPanel);
            app.PhaseSelectTree = uitree(app.GridLayout6, 'checkbox');
            app.RulesSelectButton = uibutton(app.GridLayout6, 'push');
            app.RulesSelectButton.ButtonPushedFcn = createCallbackFcn(app, @RulesSelectButtonPushed, true);
            app.RulesSelectButton.Tag = 'pathSelectButton';
            app.RulesSelectButton.Text = '···';
            app.RulesPathEditField = uieditfield(app.GridLayout6, 'text');
            app.RulesPathEditField.ValueChangedFcn = createCallbackFcn(app, @RulesPathEditFieldValueChanged, true);
            app.RulesPathEditField.Tag = 'dataPath';
            app.RulesPathEditField.Placeholder = '<rule.xlsx> path';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end