classdef paramsSettingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout3                matlab.ui.container.GridLayout
        GridLayout5                matlab.ui.container.GridLayout
        CancelButton               matlab.ui.control.Button
        OKButton                   matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        overrideEvtCheckBox        matlab.ui.control.CheckBox
        EVENTFLOWLabel             matlab.ui.control.Label
        EditEventFlowButton        matlab.ui.control.Button
        EventFlowTable             matlab.ui.control.Table
        ExperimentParametersLabel  matlab.ui.control.Label
        TRIGGERPanel               matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        comEditField               matlab.ui.control.NumericEditField
        Label_13                   matlab.ui.control.Label
        triggerTypeDropDown        matlab.ui.control.DropDown
        Label_12                   matlab.ui.control.Label
        addressEditField           matlab.ui.control.NumericEditField
        Label_11                   matlab.ui.control.Label
        STIMULUSPanel              matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        Label_14                   matlab.ui.control.Label
        volumeEditField            matlab.ui.control.NumericEditField
        VolumnLabel                matlab.ui.control.Label
        nRepeatEditField           matlab.ui.control.NumericEditField
        overrideNtrialCheckBox     matlab.ui.control.CheckBox
        Label_8                    matlab.ui.control.Label
        fsEditField                matlab.ui.control.NumericEditField
        Label_9                    matlab.ui.control.Label
        kHzLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        app_parent
        params
        EvtsPreset
    end
    
    methods (Access = private)
        
        function [ok, report] = validate_(app, ev)
            tol = 1e-9;
            msgs = strings(0,1);

            % Start unique and fixed
            startIdx = find(ev.kind=="start");
            if numel(startIdx) > 1
                msgs(end+1) = "Start event must be single.";
            end
            if ~isempty(startIdx)
                s = ev(startIdx,:);
                if abs(s.tStart) > tol || abs(s.tEnd) > tol
                    msgs(end+1) = "Start must be fixed at tStart=tEnd=0.";
                end
                if s.triggerMode=="keyboard" && strlength(strtrim(s.triggerKey))==0
                    msgs(end+1) = "Start triggerMode=keyboard requires a non-empty key.";
                end
            end

            % Basic timing validity
            if any(ev.tStart < -tol) || any(ev.tEnd < ev.tStart - tol)
                msgs(end+1) = "Invalid timing: tStart>=0 and tEnd>=tStart required.";
            end

            % Jitter validity (for those that use it)
            hasJ = (ev.kind=="stimuli") | (ev.kind=="cue") | (ev.kind=="choice");
            if any(ev.jitterA(hasJ) > ev.jitterB(hasJ) + tol)
                msgs(end+1) = "Invalid jitter range: jitterA must be <= jitterB.";
            end

            % Stimuli/Cue overlap: only allowed if modality differs
            sc = ev(ev.kind=="stimuli" | ev.kind=="cue",:);
            if height(sc) > 1
                sc = sortrows(sc,"tStart");
                for i = 1:height(sc)-1
                    for j = i+1:height(sc)
                        if sc.tStart(j) >= sc.tEnd(i) - tol
                            break
                        end
                        % overlap found
                        if sc.modality(i) == sc.modality(j)
                            msgs(end+1) = sprintf("Stimuli/Cue overlap invalid: same modality (%s) between id %d and %d.", ...
                                sc.modality(i), sc.id(i), sc.id(j));
                        end
                    end
                end
            end

            % Choice overlap forbidden (default strict)
            ch = ev(ev.kind=="choice",:);
            if height(ch) > 1
                ch = sortrows(ch,"tStart");
                for i = 2:height(ch)
                    if ch.tStart(i) < ch.tEnd(i-1) - tol
                        msgs(end+1) = sprintf("Choice overlap invalid between id %d and %d.", ch.id(i-1), ch.id(i));
                    end
                end
            end

            % Choice keys non-empty
            if any(ev.kind=="choice")
                for i = find(ev.kind=="choice").'
                    keys = strtrim(split(ev.validKeys(i), ","));
                    keys(keys=="") = [];
                    if isempty(keys)
                        msgs(end+1) = sprintf("Choice id %d: validKeys cannot be empty.", ev.id(i));
                    end
                end
            end

            % Identifer
            idx = ev.kind=="stimuli" | ev.kind=="cue";
            if sum(idx) > 1 && all(ev.identifier(idx)=="")
                msgs(end+1) = sprintf("Identifer missing: when the number of stimuli/cue exceeds 1, each should be given an identifier.");
            end

            ok = isempty(msgs);
            report = strjoin(msgs, newline);
        end

        function getPresetEvts(app)
            if isfield(app.params, "Evts") && ~isempty(app.params.Evts)
                app.EvtsPreset = app.params.Evts;
            else
                currentFolder = fileparts(mfilename("fullpath"));
                f = fullfile(currentFolder, "config", "eventFlow_passive.mat");
                if exist(f, "file")
                    S = load(fullfile(currentFolder, "config", "eventFlow_passive.mat"), "events");
                    app.EvtsPreset = S.events;
                else
                    app.EvtsPreset = table('Size',[0, 14], ...
                                   'VariableTypes', ["double","string","string","double","double","double","double","double","string","string","double","string","string","double"], ...
                                   'VariableNames', ["id","kind","modality","tStart","tEnd","jitterA","jitterB","maxDur","identifier","triggerMode","validWindow","validKeys","triggerKey","seq"]);
                end
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent, params)
            app.app_parent = app_parent;

            app.UIFigure.Name = 'Parameter settings';
            
            if ~isempty(params)
                try
                    app.params                       = params;
                    app.nRepeatEditField.Value       = params.nRepeat;
                    app.fsEditField.Value            = params.fs / 1e3; % kHz
                    app.volumeEditField.Value        = params.volume;
                    app.triggerTypeDropDown.Value    = params.triggerType;
                    app.addressEditField.Value       = params.address;
                    app.comEditField.Value           = params.COM;
                    app.overrideNtrialCheckBox.Value = params.useSettingnRepeat;
                    app.overrideEvtCheckBox.Value    = params.useSettingEventFlow;
                catch ME
                    throw(ME);
                end
            end

            if strcmpi(params.triggerType, 'LTP')
                app.addressEditField.Enable = 'on';
            else
                app.addressEditField.Enable = 'off';
            end

            if strcmpi(params.triggerType, 'COM')
                app.comEditField.Enable = 'on';
            else
                app.comEditField.Enable = 'off';
            end

            app.getPresetEvts();
            app.EventFlowTable.ColumnName = app.EvtsPreset.Properties.VariableNames;
            app.EventFlowTable.Data = app.EvtsPreset;
            app.EventFlowTable.ColumnEditable = true;
            app.EventFlowTable.ColumnSortable = true;
            app.EventFlowTable.Multiselect = "on";
            app.EventFlowTable.FontSize = 14;

            movegui(app.UIFigure, "center")
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.params.nRepeat             = app.nRepeatEditField.Value;
            app.params.fs                  = app.fsEditField.Value * 1e3; % Hz
            app.params.volume              = app.volumeEditField.Value;
            app.params.triggerType         = app.triggerTypeDropDown.Value;
            app.params.address             = app.addressEditField.Value;
            app.params.COM                 = app.comEditField.Value;
            app.params.useSettingnRepeat   = app.overrideNtrialCheckBox.Value;
            app.params.useSettingEventFlow = app.overrideEvtCheckBox.Value;
            app.app_parent.params = app.params;
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: triggerTypeDropDown
        function triggerTypeDropDownValueChanged(app, event)
            value = app.triggerTypeDropDown.Value;
            if strcmpi(value, 'LTP')
                app.addressEditField.Enable = 'on';
            else
                app.addressEditField.Enable = 'off';
            end
            if strcmpi(value, 'COM')
                app.comEditField.Enable = 'on';
            else
                app.comEditField.Enable = 'off';
            end
        end

        % Button pushed function: EditEventFlowButton
        function EditEventFlowButtonPushed(app, event)
            if isfield(app.params, "Evts") && ~isempty(app.params.Evts)
                preset = app.params.Evts;
            else
                preset = app.EvtsPreset;
            end
            eventFlow = eventFlowApp(preset);
            uiwait(eventFlow.UIFigure);
            if isvalid(eventFlow)
                app.params.Evts = eventFlow.Evts;
                app.EventFlowTable.Data = app.params.Evts;
                delete(eventFlow);
            end
        end

        % Cell edit callback: EventFlowTable
        function EventFlowTableCellEdit(app, event)
            % For safety: validate after any direct edit in table
            newEv = app.EventFlowTable.Data;
            [ok, report] = app.validate_(newEv);
            if ~ok
                uialert(app.UIFigure, report, "Invalid schedule");
                app.EventFlowTable.Data = app.EvtsPreset;
                return
            end
            app.params.Evts = newEv;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Parameter settings';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.STIMULUSPanel = uipanel(app.GridLayout3);
            app.STIMULUSPanel.Title = 'STIMULUS';
            app.GridLayout2 = uigridlayout(app.STIMULUSPanel);
            app.kHzLabel = uilabel(app.GridLayout2);
            app.kHzLabel.Text = 'kHz';
            app.Label_9 = uilabel(app.GridLayout2);
            app.Label_9.Text = 'Device sample rate';
            app.fsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.fsEditField.Limits = [0 Inf];
            app.fsEditField.Value = 384;
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = 'Number of trials';
            app.overrideNtrialCheckBox = uicheckbox(app.GridLayout2);
            app.overrideNtrialCheckBox.Text = 'override';
            app.nRepeatEditField = uieditfield(app.GridLayout2, 'numeric');
            app.nRepeatEditField.Limits = [1 Inf];
            app.nRepeatEditField.ValueDisplayFormat = '%.0f';
            app.nRepeatEditField.Value = 40;
            app.VolumnLabel = uilabel(app.GridLayout2);
            app.VolumnLabel.Text = 'Sound volume';
            app.volumeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.volumeEditField.Limits = [0 1];
            app.volumeEditField.Value = 0.3;
            app.Label_14 = uilabel(app.GridLayout2);
            app.Label_14.Text = '(0~1)';
            app.TRIGGERPanel = uipanel(app.GridLayout3);
            app.TRIGGERPanel.Title = 'TRIGGER';
            app.GridLayout = uigridlayout(app.TRIGGERPanel);
            app.Label_11 = uilabel(app.GridLayout);
            app.Label_11.Text = 'LTP port';
            app.addressEditField = uieditfield(app.GridLayout, 'numeric');
            app.addressEditField.Limits = [0 Inf];
            app.addressEditField.Value = 888;
            app.Label_12 = uilabel(app.GridLayout);
            app.Label_12.Text = 'Trigger mode';
            app.triggerTypeDropDown = uidropdown(app.GridLayout);
            app.triggerTypeDropDown.Items = {'None', 'LTP', 'COM', 'TriggerBox'};
            app.triggerTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @triggerTypeDropDownValueChanged, true);
            app.triggerTypeDropDown.Value = 'None';
            app.Label_13 = uilabel(app.GridLayout);
            app.Label_13.Text = 'COM port';
            app.comEditField = uieditfield(app.GridLayout, 'numeric');
            app.comEditField.Limits = [0 Inf];
            app.comEditField.ValueDisplayFormat = '%.0f';
            app.comEditField.Value = 1;
            app.ExperimentParametersLabel = uilabel(app.GridLayout3);
            app.ExperimentParametersLabel.Text = 'Experiment Parameters';
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.EventFlowTable = uitable(app.GridLayout4);
            app.EventFlowTable.ColumnName = '';
            app.EventFlowTable.RowName = {};
            app.EventFlowTable.CellEditCallback = createCallbackFcn(app, @EventFlowTableCellEdit, true);
            app.EditEventFlowButton = uibutton(app.GridLayout4, 'push');
            app.EditEventFlowButton.ButtonPushedFcn = createCallbackFcn(app, @EditEventFlowButtonPushed, true);
            app.EditEventFlowButton.Text = 'Edit flow';
            app.EVENTFLOWLabel = uilabel(app.GridLayout4);
            app.EVENTFLOWLabel.Text = 'EVENT FLOW';
            app.overrideEvtCheckBox = uicheckbox(app.GridLayout4);
            app.overrideEvtCheckBox.Text = 'override';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.OKButton = uibutton(app.GridLayout5, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tag = 'confirmButton';
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout5, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = paramsSettingApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end