classdef startEventEditApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        KindSTARTLabel            matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        OKButton                  matlab.ui.control.Button
        NoteJitteronlychangesintertrialintervalITILabel  matlab.ui.control.Label
        JitterBEdit               matlab.ui.control.NumericEditField
        StartjittertosecLabel     matlab.ui.control.Label
        JitterAEdit               matlab.ui.control.NumericEditField
        StartjitterfromsecLabel   matlab.ui.control.Label
        KeyEdit                   matlab.ui.control.EditField
        KeykeyboardLabel          matlab.ui.control.Label
        ModeDropDown              matlab.ui.control.DropDown
        TriggermodeDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ev struct
        onCommit function_handle
    end
    
    methods (Access = private)
        
        function load_(app)
            if strlength(app.ev.triggerMode)==0
                app.ev.triggerMode = "auto";
            end
            app.ModeDropDown.Value = char(app.ev.triggerMode);
            app.KeyEdit.Value = char(app.ev.triggerKey);
            app.ModeDropDownValueChanged();
            app.JitterAEdit.Value = app.ev.jitterA;
            app.JitterBEdit.Value = app.ev.jitterB;
        end

    end
    
    methods (Access = public)
        
        function showModal(app, parentFig)
            app.UIFigure.WindowStyle = 'modal';
            app.UIFigure.Visible = 'on';
            if nargin>1 && ~isempty(parentFig)
                movegui(app.UIFigure, 'center');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ev, onCommit)
            app.ev = ev;
            app.onCommit = onCommit;
            app.load_();
            movegui(app.UIFigure, 'center');
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            isKb = strcmp(value, 'keyboard');
            app.KeyEdit.Enable = matlab.lang.OnOffSwitchState(isKb);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.ev.kind = "start";
            app.ev.tStart = 0;
            app.ev.tEnd = 0;
            app.ev.triggerMode = string(app.ModeDropDown.Value);
            app.ev.triggerKey = string(app.KeyEdit.Value);
            if app.ev.triggerMode=="auto"
                app.ev.triggerKey = "";
            end
            app.ev.jitterA = app.JitterAEdit.Value;
            app.ev.jitterB = app.JitterBEdit.Value;

            app.onCommit(app.ev);
            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'Start event editor';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TriggermodeDropDownLabel = uilabel(app.GridLayout);
            app.TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.ModeDropDown = uidropdown(app.GridLayout);
            app.ModeDropDown.Items = {'auto', 'keyboard'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'auto';
            app.KeykeyboardLabel = uilabel(app.GridLayout);
            app.KeykeyboardLabel.Text = 'Key (keyboard)';
            app.KeyEdit = uieditfield(app.GridLayout, 'text');
            app.KeyEdit.Placeholder = 'space';
            app.StartjitterfromsecLabel = uilabel(app.GridLayout);
            app.StartjitterfromsecLabel.Text = 'Start jitter from (sec):';
            app.JitterAEdit = uieditfield(app.GridLayout, 'numeric');
            app.StartjittertosecLabel = uilabel(app.GridLayout);
            app.StartjittertosecLabel.Text = 'Start jitter to (sec):';
            app.JitterBEdit = uieditfield(app.GridLayout, 'numeric');
            app.NoteJitteronlychangesintertrialintervalITILabel = uilabel(app.GridLayout);
            app.NoteJitteronlychangesintertrialintervalITILabel.Text = 'Note: Jitter only changes inter-trial interval (ITI).';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.KindSTARTLabel = uilabel(app.GridLayout);
            app.KindSTARTLabel.Text = 'Kind: START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = startEventEditApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end