classdef stimuliEventEditApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        CancelButton                  matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
        IdentifierEdit                matlab.ui.control.EditField
        IdentifierEditFieldLabel      matlab.ui.control.Label
        MaxDurEdit                    matlab.ui.control.NumericEditField
        MaxdurationsecEditFieldLabel  matlab.ui.control.Label
        JitterBEdit                   matlab.ui.control.NumericEditField
        StarttimejittertosecEditFieldLabel  matlab.ui.control.Label
        JitterAEdit                   matlab.ui.control.NumericEditField
        StarttimejitterfromsecEditFieldLabel  matlab.ui.control.Label
        StartEdit                     matlab.ui.control.NumericEditField
        StarttimesecEditFieldLabel    matlab.ui.control.Label
        TypeDropDown                  matlab.ui.control.DropDown
        TypemodalityDropDownLabel     matlab.ui.control.Label
        KindLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        ev struct
        onCommit function_handle
    end
    
    methods (Access = public)
        
        function showModal(app, ~)
            app.UIFigure.WindowStyle = 'modal';
            app.UIFigure.Visible = 'on';
            movegui(app.UIFigure,'center');
        end

    end
    
    methods (Access = private)
        
        function load_(app)
            app.KindLabel.Text = "Kind: " + upper(string(app.ev.kind));
            if strlength(app.ev.modality)==0
                app.ev.modality = "auditory";
            end
            app.TypeDropDown.Value = char(app.ev.modality);
            app.StartEdit.Value = app.ev.tStart;
            app.JitterAEdit.Value = app.ev.jitterA;
            app.JitterBEdit.Value = app.ev.jitterB;
            if ~isfinite(app.ev.maxDur) || app.ev.maxDur<=0, app.ev.maxDur = 0.3; end
            app.MaxDurEdit.Value = app.ev.maxDur;
            app.IdentifierEdit.Value = char(app.ev.identifier);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ev, onCommit)
            app.ev = ev;
            app.onCommit = onCommit;
            app.load_();
            movegui(app.UIFigure, 'center');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.ev.modality = string(app.TypeDropDown.Value);
            app.ev.tStart = app.StartEdit.Value;
            app.ev.jitterA = app.JitterAEdit.Value;
            app.ev.jitterB = app.JitterBEdit.Value;
            app.ev.maxDur = app.MaxDurEdit.Value;
            app.ev.identifier = string(app.IdentifierEdit.Value);

            app.onCommit(app.ev);
            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'Stimulus/cue event editor';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.KindLabel = uilabel(app.GridLayout);
            app.KindLabel.Text = 'Kind: ';
            app.TypemodalityDropDownLabel = uilabel(app.GridLayout);
            app.TypemodalityDropDownLabel.Text = 'Type (modality)';
            app.TypeDropDown = uidropdown(app.GridLayout);
            app.TypeDropDown.Items = {'auditory', 'visual'};
            app.TypeDropDown.Value = 'auditory';
            app.StarttimesecEditFieldLabel = uilabel(app.GridLayout);
            app.StarttimesecEditFieldLabel.Text = 'Start time (sec)';
            app.StartEdit = uieditfield(app.GridLayout, 'numeric');
            app.StarttimejitterfromsecEditFieldLabel = uilabel(app.GridLayout);
            app.StarttimejitterfromsecEditFieldLabel.Text = 'Start time jitter from (sec)';
            app.JitterAEdit = uieditfield(app.GridLayout, 'numeric');
            app.StarttimejittertosecEditFieldLabel = uilabel(app.GridLayout);
            app.StarttimejittertosecEditFieldLabel.Text = 'Start time jitter to (sec)';
            app.JitterBEdit = uieditfield(app.GridLayout, 'numeric');
            app.MaxdurationsecEditFieldLabel = uilabel(app.GridLayout);
            app.MaxdurationsecEditFieldLabel.Text = 'Max duration (sec)';
            app.MaxDurEdit = uieditfield(app.GridLayout, 'numeric');
            app.MaxDurEdit.Value = 1;
            app.IdentifierEditFieldLabel = uilabel(app.GridLayout);
            app.IdentifierEditFieldLabel.Text = 'Identifier';
            app.IdentifierEdit = uieditfield(app.GridLayout, 'text');
            app.IdentifierEdit.Placeholder = 'e.g., A1';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimuliEventEditApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end